classdef Work7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        MENU_Exit                      matlab.ui.container.Menu
        About                          matlab.ui.container.Menu
        TABG_Audio                     matlab.ui.container.TabGroup
        TAB_AudioAnalyzer              matlab.ui.container.Tab
        L_AnalyzerFig                  matlab.ui.control.Label
        L_AudioAnalyze                 matlab.ui.control.Label
        L_SimplingPara                 matlab.ui.control.Label
        BTN_PlotChannelData            matlab.ui.control.Button
        BTN_PlayAduio                  matlab.ui.control.Button
        BTN_StartSimpling              matlab.ui.control.Button
        DP_SimplingTime                matlab.ui.control.DropDown
        Label_4                        matlab.ui.control.Label
        DP_SimplingFreq                matlab.ui.control.DropDown
        Label_3                        matlab.ui.control.Label
        DP_SimplingDepth               matlab.ui.control.DropDown
        Label_2                        matlab.ui.control.Label
        DP_InputDeivce                 matlab.ui.control.DropDown
        Label                          matlab.ui.control.Label
        FIG_Right                      matlab.ui.control.UIAxes
        FIG_Left                       matlab.ui.control.UIAxes
        TABG_Generator                 matlab.ui.container.Tab
        DP_CalcuateMethod              matlab.ui.control.DropDown
        Label_9                        matlab.ui.control.Label
        BTN_CalcuateSignal             matlab.ui.control.Button
        TABG_Ch2                       matlab.ui.container.TabGroup
        TAB_SinCh2                     matlab.ui.container.Tab
        EF_SinPhaseCh2                 matlab.ui.control.NumericEditField
        EditField_6Label               matlab.ui.control.Label
        EF_SinAngularFreqCh2           matlab.ui.control.NumericEditField
        EditField_5Label               matlab.ui.control.Label
        EF_SinAmplitudeCh2             matlab.ui.control.NumericEditField
        EditField_4Label               matlab.ui.control.Label
        BTN_SignalGenerateCh2          matlab.ui.control.Button
        TAB_SquareCh2                  matlab.ui.container.Tab
        EF_SquareAngularFreqCh2        matlab.ui.control.NumericEditField
        EditField_2Label_3             matlab.ui.control.Label
        EF_SquareAmplitudeCh2          matlab.ui.control.NumericEditField
        Label_12                       matlab.ui.control.Label
        BTN_SquareGenerateCh2          matlab.ui.control.Button
        TAB_StepCH2                    matlab.ui.container.Tab
        EF_StepTimeCh2                 matlab.ui.control.NumericEditField
        Label_16                       matlab.ui.control.Label
        EF_StepAmplitudeCh2            matlab.ui.control.NumericEditField
        Label_15                       matlab.ui.control.Label
        BTN_StepGenerateCh2            matlab.ui.control.Button
        TAB_ChirpCh2                   matlab.ui.container.Tab
        EF_StopFrequenceCh2            matlab.ui.control.NumericEditField
        EditField_3Label_7             matlab.ui.control.Label
        Label_18                       matlab.ui.control.Label
        EF_ChripAmplitudeCh2           matlab.ui.control.NumericEditField
        EF_StartFrequenceCh2           matlab.ui.control.NumericEditField
        EditField_3Label_6             matlab.ui.control.Label
        EF_BandWidthCh2                matlab.ui.control.NumericEditField
        EditField_2Label_5             matlab.ui.control.Label
        BTN_ChripGenerateCh2           matlab.ui.control.Button
        TabGroup2                      matlab.ui.container.TabGroup
        TAB_SinCh1                     matlab.ui.container.Tab
        BTN_SignalGenerateCh1          matlab.ui.control.Button
        EF_SinPhaseCh1                 matlab.ui.control.NumericEditField
        EditField_3Label               matlab.ui.control.Label
        EF_SinAngularFreqCh1           matlab.ui.control.NumericEditField
        EditField_2Label               matlab.ui.control.Label
        EF_SinAmplitudeCh1             matlab.ui.control.NumericEditField
        Label_8                        matlab.ui.control.Label
        TAB_SquareCh1                  matlab.ui.container.Tab
        BTN_SquareGenerateCh1          matlab.ui.control.Button
        EF_SquareAngularFreqCh1        matlab.ui.control.NumericEditField
        EditField_2Label_2             matlab.ui.control.Label
        EF_SquareAmplitudeCh1          matlab.ui.control.NumericEditField
        Label_11                       matlab.ui.control.Label
        TAB_StepCh1                    matlab.ui.container.Tab
        BTN_StepGenerateCh1            matlab.ui.control.Button
        EF_StepTimeCh1                 matlab.ui.control.NumericEditField
        Label_14                       matlab.ui.control.Label
        EF_StepAmplitudeCh1            matlab.ui.control.NumericEditField
        Label_13                       matlab.ui.control.Label
        TAB_ChirpCh1                   matlab.ui.container.Tab
        EF_StopFrequenceCh1            matlab.ui.control.NumericEditField
        EditField_3Label_5             matlab.ui.control.Label
        EF_StartFrequenceCh1           matlab.ui.control.NumericEditField
        EditField_3Label_4             matlab.ui.control.Label
        EF_BandWidthCh1                matlab.ui.control.NumericEditField
        EditField_2Label_4             matlab.ui.control.Label
        EF_ChirpAmplitudeCh1           matlab.ui.control.NumericEditField
        Label_17                       matlab.ui.control.Label
        BTN_ChirpGenerateCh1           matlab.ui.control.Button
        FIG_Output                     matlab.ui.control.UIAxes
        FIG_Ch2                        matlab.ui.control.UIAxes
        FIG_Ch1                        matlab.ui.control.UIAxes
        TimeAnalyzer                   matlab.ui.container.Tab
        Time_PeriodValue               matlab.ui.control.TextArea
        Label_30                       matlab.ui.control.Label
        BTN_TimeAnalyzerDimensionlessAnalyze  matlab.ui.control.Button
        Time_ClearanceFactorValue      matlab.ui.control.TextArea
        Label_29                       matlab.ui.control.Label
        Time_ImpulseValue              matlab.ui.control.TextArea
        Label_28                       matlab.ui.control.Label
        Time_ShapeValue                matlab.ui.control.TextArea
        Label_27                       matlab.ui.control.Label
        Time_CrestFactorValue          matlab.ui.control.TextArea
        Label_26                       matlab.ui.control.Label
        Time_PeakValue                 matlab.ui.control.TextArea
        Label_25                       matlab.ui.control.Label
        Time_RmsValue                  matlab.ui.control.TextArea
        Label_24                       matlab.ui.control.Label
        Time_StdValue                  matlab.ui.control.TextArea
        Label_23                       matlab.ui.control.Label
        Time_VarValue                  matlab.ui.control.TextArea
        Label_22                       matlab.ui.control.Label
        Time_MeanValue                 matlab.ui.control.TextArea
        Label_21                       matlab.ui.control.Label
        Time_PeakPeakValue             matlab.ui.control.TextArea
        Label_20                       matlab.ui.control.Label
        DP_TimeAnalyzerSignalSource    matlab.ui.control.DropDown
        Label_19                       matlab.ui.control.Label
        BTN_TimeAnalyzerDimensionAnalyze  matlab.ui.control.Button
        BTN_TimeAnalyzerInputSignal    matlab.ui.control.Button
        FIG_TimeAnalyzerTimeDomain     matlab.ui.control.UIAxes
        FFT                            matlab.ui.container.Tab
        DP_FFTOvertone                 matlab.ui.control.DropDown
        Label_32                       matlab.ui.control.Label
        BTN_StartFFT                   matlab.ui.control.Button
        BTN_FFTSignalIntput            matlab.ui.control.Button
        DP_FFTSignalSource             matlab.ui.control.DropDown
        Label_31                       matlab.ui.control.Label
        FIG_FFTOrignal                 matlab.ui.control.UIAxes
        FIG_FFT                        matlab.ui.control.UIAxes
        TABG_FrequenceAnalyzer         matlab.ui.container.Tab
        BTN_FreqAnalyzerStartAnalyze   matlab.ui.control.Button
        BTN_FreqAnalyzerInputSignal    matlab.ui.control.Button
        DP_FreqAnalyzerSignalSource    matlab.ui.control.DropDown
        Label_10                       matlab.ui.control.Label
        FIG_FreqAnalyzerReal           matlab.ui.control.UIAxes
        FIG_FreqAnalyzerImag           matlab.ui.control.UIAxes
        FIG_FreqAnalyzerPhaseSpectrum  matlab.ui.control.UIAxes
        FIG_FreqAnalyzerAmplitudeSpectrum  matlab.ui.control.UIAxes
        FIG_FreqAnalyzerPowerSpectrum  matlab.ui.control.UIAxes
        FIG_FreqAnalyzerOrignalSignal  matlab.ui.control.UIAxes
        TAB_Filter                     matlab.ui.container.Tab
        EditField                      matlab.ui.control.NumericEditField
        Label_36                       matlab.ui.control.Label
        BTN_StartFilter                matlab.ui.control.Button
        BTN_InputSignal                matlab.ui.control.Button
        DP_Filter                      matlab.ui.control.DropDown
        Label_35                       matlab.ui.control.Label
        ED_CutFreq                     matlab.ui.control.NumericEditField
        Label_34                       matlab.ui.control.Label
        ED_PassFreq                    matlab.ui.control.NumericEditField
        Label_33                       matlab.ui.control.Label
        FIG_FiltedSignal               matlab.ui.control.UIAxes
        FIG_FilterInputSignal          matlab.ui.control.UIAxes
        Tab_2                          matlab.ui.container.Tab
    end

    
    % ヽ(=^･ω･^=)丿 %

    % Save global properties which use to transfer data between function and callbacks
    properties (Access = private)
        g_input_device = -1 % (global variable) Set default input device (-1 means the first input device of computer) 
        g_simpling_depth = 16 % (global variable) Set default simpling depth (larger simpling depth can catch more audio specific)
        g_simpling_freq = 44100 % (global variable) Set default simpling freqency (44100Hz is for simpling human voice)
        g_simpling_time = 5 % (global variable) Set default simpling time (larger simpling time would catch longer audio)
        g_obj_audiorecorder % (global object) Global object of audio recorder (use for transfer data)
        g_audio_data    % (global variable) Global audio data matrix (use for transfer data)
        
        g_calcuate_method = 0 %(global variable) Set default calcuate method (0 means ch1+ch2)
        
        g_signal_ts = 1/50; % (global variable) Simpling rate
        g_signal_t = 0:1/50:10-1/50; % (global variable) Simpling dots
        g_signal_conv_t % (global variable) Conv signal simpling dots

        g_signal_ch1 % (global variable) Output signal of generator CH1
        g_signal_ch2 % (global variable) Output signal of generator CH2
        
        g_sin_amplitude_ch1 % (global variable) Sin signal amplitude of CH1
        g_sin_angular_freq_ch1 % (global variable) Sin signal angular frequence of CH1
        g_sin_phase_ch1 % (global variable) Sin signal phase of CH1
        
        g_sin_amplitude_ch2 
        g_sin_angular_freq_ch2 
        g_sin_phase_ch2 
        
        g_output_signal_t
        

        g_output_signal % (global variable) Signal generator output

        g_signal_source = 1 % (gloable variable) Set default signal source of signal analyzer (1 means input from signal generator)

        g_fft_signal % (global variable) Output of fast fourier transfer


        g_square_amplitude_ch1 % (global variable) Square signal amplitude of CH1
        g_square_freq_ch1 % (global variable) Square signal  frequence of CH1

        g_square_amplitude_ch2
        g_square_freq_ch2

        g_step_amplitude_ch1 % (global variable) Step signal amplitude of CH1
        g_step_time_ch1

        g_step_amplitude_ch2
        g_step_time_ch2

        g_chirp_amplitude_ch1 % (global variable) Chirp signal amplitude of CH1
        g_chirp_bandwidth_ch1 % (global variable) Chirp signal bandwidth of CH1
        g_chirp_start_freq_ch1 % (global variable) Chirp signal start frequence of CH1
        g_chirp_stop_freq_ch1 % (global variable) Chirp signal stop frequence of CH1

        g_chirp_amplitude_ch2
        g_chirp_bandwdth_ch2
        g_chirp_start_freq_ch2
        g_chirp_stop_freq_ch2

        g_peak_peak_value
        g_mean_value
        g_var_value
        g_std_value
        g_rms_value
        g_peak_value
        g_crest_factor_value
        g_shape_value
        g_impulse_value
        g_clearance_factor_value
        g_period_value

        g_fft_overtone_value = 1;

        % Filter
        g_filter_pass_freq
        g_filter_cut_freq
        g_filter_type = 0
        g_filter_order
    end
    
    % User functions
    methods (Access = private)
        function userStartSimpling(app)
            input_device = app.g_input_device;   % Get input device from dropbox
            simpling_depth = app.g_simpling_depth; % Get simpling depth from dropbox
            simpling_freq = app.g_simpling_freq; % Get simpling freqence from dropbox
            simpling_time = app.g_simpling_time; % Get simpling time from dropbox
            obj_audiorecorder = audiorecorder(simpling_freq,simpling_depth,1,input_device);  % Create a audiorecorder object 
            recordblocking(obj_audiorecorder,simpling_time); % Start record audio which lenth is simpling time
            app.g_obj_audiorecorder = obj_audiorecorder; % Transfer audiorecorder object to global object
            play(app.g_obj_audiorecorder); % Play audio
        end
        
        function results = userGenerateSinSignalCh1(app)
            A = app.g_sin_amplitude_ch1;
            f = app.g_sin_angular_freq_ch1;
            w = 2*pi*f;
            p = app.g_sin_phase_ch1;
            sin_signal_ch1 = A*sin(w*app.g_signal_t+p); % Sin signal
            app.g_signal_ch1 = sin_signal_ch1;
            results = sin_signal_ch1;
        end
        
        
        function results = userGenerateSinSignalCh2(app)
            A = app.g_sin_amplitude_ch2;
            f = app.g_sin_angular_freq_ch2;
            w = 2*pi*f;
            p = app.g_sin_phase_ch2;
            sin_signal_ch2 = A*sin(w*app.g_signal_t+p);
            app.g_signal_ch2 = sin_signal_ch2;
            results = sin_signal_ch2;
            
        end
        
        function results = userCalcuateMethod(app)
            switch app.g_calcuate_method
                case 0
                    if (isempty(app.g_signal_ch2))
                        results = app.g_signal_ch1;
                    elseif (isempty(app.g_signal_ch1))
                        results = app.g_signal_ch2;
                    else
                        results = app.g_signal_ch1 + app.g_signal_ch2;
                    end
                case 1
                    if (isempty(app.g_signal_ch2))
                        results = app.g_signal_ch1;
                    elseif (isempty(app.g_signal_ch1))
                        results = app.g_signal_ch2;
                    else
                        results = app.g_signal_ch1 - app.g_signal_ch2;
                    end
                case 2
                    if (isempty(app.g_signal_ch2))
                        results = app.g_signal_ch1;
                    elseif (isempty(app.g_signal_ch1))
                        results = app.g_signal_ch2;
                    else
                        results = app.g_signal_ch1 .* app.g_signal_ch2;
                    end
                case 3
                    if (isempty(app.g_signal_ch2))
                        results = app.g_signal_ch1;
                    elseif (isempty(app.g_signal_ch1))
                        results = app.g_signal_ch2;
                    else
                        results = app.g_signal_ch1 ./ app.g_signal_ch2;
                    end
                case 4
                    if (isempty(app.g_signal_ch2))
                        results = app.g_signal_ch1;
                    elseif (isempty(app.g_signal_ch1))
                        results = app.g_signal_ch2;
                    else
                        results = userConvSignal(app);
                    end
            end
        end
        
        function userFFTShift(app)
            y = fft(app.g_output_signal); % Do fft 
            fs = 1/app.g_signal_ts;
            f = (0:length(y)-1)*fs/length(y);
            n = length(app.g_output_signal);
            fshift = (-n/2:n/2-1)*(fs/n);
            yshift = fftshift(y); % Do fftshift
            app.g_fft_signal = yshift;
            plot(app.FIG_FreqAnalyzerPowerSpectrum,fshift,yshift)

%             ly = length(y);
%             f1 = (-ly/2:ly/2-1)/ly*fs; % Calcualte phase
%             tol = 1e-6;
%             yshift(abs(yshift) < tol) = 0;
%             theta = angle(yshift);
%             if(app.g_signal_source == 0)
%             else
%                 stem(app.FIG_FreqAnalyzerAmplitudeSpectrum,f1,theta/pi);
%             end
            
                        
        end
        
        function results = userGenerateSquareSignalCh1(app)
            A = app.g_square_amplitude_ch1;
            F = app.g_square_freq_ch1\(2*pi);
            T = app.g_signal_t;
            y=A*square(F*T); % Square signal
            results = y;
        end

        function results = userGenerateSquareSignalCh2(app)
            A = app.g_square_amplitude_ch2;
            F = app.g_square_freq_ch2\(2*pi);
            T = app.g_signal_t;
            y=A*square(F*T);
            results = y;
        end
        
        function results = userGenerateStepSignalCh1(app)
            A = app.g_step_amplitude_ch1;
            T = app.g_signal_t;
            time = app.g_step_time_ch1;
            y = stepSeq(app,T,time); % Step signal
            results = y;
        end
        
        function results = stepSeq(~,t,time)
            y = (t - time>=0);
            results = y;
            
        end

        function results = userGenerateStepSignalCh2(app)
            A = app.g_step_amplitude_ch2;
            T = app.g_signal_t;
            time = app.g_step_time_ch2;
            y = stepSeq(app,T,time);
            results = y;
        end
        
        function results = userGenerateChirpSignalCh1(app)
            T = app.g_chirp_bandwidth_ch1;
            A = app.g_chirp_amplitude_ch1;
            start_freq = app.g_chirp_start_freq_ch1;
            stop_freq=app.g_chirp_stop_freq_ch1;
            T=10e-5; % Bandwidth
            N=length(app.g_signal_t); % Simpling dots
            t=linspace(-T/2,T/2,N); % T
            y=A*(chirp(t,start_freq,T,stop_freq)); % Chirp signal
            results = y;

        end

         function results = userGenerateChirpSignalCh2(app)
            T = app.g_chirp_bandwdth_ch2;
            A = app.g_chirp_amplitude_ch2;
            start_freq = app.g_chirp_start_freq_ch2;
            stop_freq=app.g_chirp_stop_freq_ch2;
            T=10e-5;
            N=length(app.g_signal_t);
            t=linspace(-T/2,T/2,N);
            y=A*(chirp(t,start_freq,T,stop_freq));
            results = y;

        end
        
        function results =  userConvSignal(app)
            conved_signal = conv(app.g_signal_ch1,app.g_signal_ch2);
            conved_signal = conved_signal*app.g_signal_ts;
            results = conved_signal;
            k = 2*length(app.g_signal_t)-1;
            app.g_signal_conv_t = linspace(2*app.g_signal_t(1),2*app.g_signal_t(end),k);
        end
        
        function results = userCalcuatePeakPeakValue(app)
            max_value = max(app.g_output_signal);
            min_value = min(app.g_output_signal);
            peak_peak_value = abs(max_value-min_value);
            results = peak_peak_value;
        end
        
        function results = userCalcuateMean(app)
            mean_value = mean(app.g_output_signal);
            results = mean_value;
            
        end
        
        function results = userCalcuateVar(app)
            var_value = var(app.g_output_signal);
            results = var_value;
            
        end
        
        function results = userCalcuateStdValue(app)
            std_value = std(app.g_output_signal);
            results = std_value;
            
        end
        
        function results = userCalcuateRmsValue(app)
            rms_value = rms(app.g_output_signal);
            results = rms_value;
            
        end
        
        function results = userCalcuatePeakValue(app)
            peak_value = max(app.g_output_signal);
            results = peak_value;
            
        end
        
        function results = userCalcuateCrestFactorValue(app)
            crest_factor_value = app.g_peak_peak_value / app.g_rms_value;
            results = crest_factor_value;
            
        end
        
        function results = userCalcuateShapeValue(app)
            shape_value = app.g_rms_value / mean(abs(app.g_output_signal));
            results = shape_value;
            
        end
        
        function results = userCalcuateImpulseValue(app)
            impulse_value = app.g_peak_peak_value / mean(abs(app.g_output_signal));
            results = impulse_value;
            
        end
        
        function results = userCalcuateClearanceValue(app)
            clearance_value = app.g_peak_peak_value / mean(sqrt(abs(app.g_output_signal)))^2;
            results = clearance_value;
            
        end
        
        function results = userCalcuatePeriodValue(app)
            max_value = max(app.g_output_signal);
            index = find(app.g_output_signal == max_value,2);
            if (length(index) > 1)
                period = abs(index(2)-index(1)) / (app.g_signal_ts * 10000);
            else
                period = "/";
            end
            results = period;
            
        end
        
        function results = userCalcuateFFTPara(app)
            t = app.g_signal_t;
            w0 = 2*pi;
            y = app.g_output_signal;
            plot(app.FIG_FFT,t,y);
            n_max = (1:str2num(app.g_fft_overtone_value));
            N = length(n_max);
            for k = 1:N
                n = 1:2:n_max(k);
                b = 4./(pi*n);
                x = b * sin(w0*n'*t);
                pause(1)
                plot(app.FIG_FFT,t,x);
%                 title(['最大谐波数=',num2str(n_max(k))]);
            end
        end
        
        function results = userCalcuatePowerSpectrum(app)
            xn = app.g_output_signal;
            Fs = 1/app.g_signal_ts;
            window=boxcar(length(xn)); %矩形窗
            nfft=1024;
            [Pxx,f]=periodogram(xn,window,nfft,Fs); %直接法
            plot(app.FIG_FreqAnalyzerPowerSpectrum,f,10*log10(Pxx));
        end


        function results = userCalcuateAmplitudeSpectrum(app)
            l = length(app.g_output_signal);
            Y = fft(app.g_output_signal,l);
            freq = (0:l/2)*(1/app.g_signal_ts/l);
            p2 = abs(Y/l);
            p1 = p2(1:l/2+1,:);
            p1(2:end-1,:) = 2*p1(2:end-1,:);
            plot(app.FIG_FreqAnalyzerAmplitudeSpectrum,freq,p1);
        end

        
        function results = userCalcuatePhaseSpectrum(app)
            L = length(app.g_output_signal);
            Y = fft(app.g_output_signal,L);
            p2 = abs(Y/L);
            p2(2:end-1,:)=2*p2(2:end-1,:);
            tol=1e-6;
            for i=1:size(Y,2)
                Y(p2(:,i)<tol,i) = 0;
                theta(:,i) = angle(Y(:,i))/pi;
            end
            Theta=theta(1:L/2+1,:);
            Theta
            plot(app.FIG_FreqAnalyzerPhaseSpectrum,Theta);
        end


        function results = userCalcueateReal(app)
            L = length(app.g_output_signal);
            Y = fft(app.g_output_signal,L);
            p1 = real(Y)
            plot(app.FIG_FreqAnalyzerReal,p1);
            
        end
        
        function results = userCalcuateImag(app)
            L = length(app.g_output_signal);
            Y = fft(app.g_output_signal,L);
            p1 = imag(Y)
            plot(app.FIG_FreqAnalyzerImag,p1);
            
        end
        
        function results = filterStart(app)
            switch app.g_filter_type
                case 0
                    filterLowPass(app);
                case 1

            end
        end
        
        function results = filterLowPass(app)
            Fs = 1/app.g_signal_ts;
            Fc = app.g_filter_cut_freq;
            N = app.g_filter_order;
            T = app.g_signal_ts;
            L = length(app.g_signal_t);
            t = (0:L-1)*T;
            S = app.g_output_signal;
            X = S+2*randn(size(t))
            win = hann(N+1);
            b = fir1(N,Fc/(Fs/2),'low',win);
            Y = filter(b,1,X);
            plot(app.FIG_FiltedSignal,Y);

        end

        function results = filterHighPass(app)
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DP_InputDeivce
        function getInputDevice(app, event)
            input_device = app.DP_InputDeivce.Value;
            switch input_device
                case 'Audio Device 1'
                    app.g_input_device = 1;    % Transfer input device number from dropbox to global variable
                case 'Audio Device 2'
                    app.g_input_device = 2;
            end
        end

        % Value changed function: DP_SimplingDepth
        function getSimplingDepth(app, event)
            simpling_depth = app.DP_SimplingDepth.Value;
            switch simpling_depth
                case '8bit'
                    app.g_simpling_depth = 8;   % Transfer simpling depth from dropbox to global variable
                case '16bit'
                    app.g_simpling_depth = 16;
                case '24bit'
                    app.g_simpling_depth = 24;
                case '32bit'
                    app.g_obj_audiorecorder.simpling_depth = 32;
            end
        end

        % Value changed function: DP_SimplingTime
        function getSimplingTime(app, event)
            simpling_time = app.DP_SimplingTime.Value;
            switch simpling_time
                case '5s'
                    app.g_simpling_time = 5;    % Transfer simpling time from dropbox to global variable
                case '10s'
                    app.g_simpling_time = 10;
                case '15s'
                    app.g_simpling_time = 15;
                case '20s'
                    app.g_simpling_time = 20;
            end
            
        end

        % Value changed function: DP_SimplingFreq
        function getSimplingFreq(app, event)
            simpling_freq = app.DP_SimplingFreq.Value;
            switch simpling_freq
                case '44100HZ'
                    app.g_simpling_freq = 44100;    % Transfer simpling freqence from dropbox to global variable
            end
        end

        % Button pushed function: BTN_StartSimpling
        function startSimpling(app, event)
            userStartSimpling(app) % Start simpling
        end

        % Button pushed function: BTN_PlayAduio
        function playAudio(app, event)
            play(app.g_obj_audiorecorder)   % Play recorded audio
            audio_data = getaudiodata(app.g_obj_audiorecorder);  % Read recorded audio to matrix
            app.g_audio_data = audio_data;
            app.g_output_signal = audio_data;
        end

        % Button pushed function: BTN_PlotChannelData
        function PlotChannelData(app, event)
            plot(app.FIG_Left,app.g_audio_data)   % Plot left channel data
            plot(app.FIG_Right,app.g_audio_data)  % Plot right channel data
        end

        % Value changed function: EF_SinAmplitudeCh1
        function getSinAmplitudeCh1(app, event)
             app.g_sin_amplitude_ch1 = app.EF_SinAmplitudeCh1.Value;
             
        end

        % Value changed function: EF_SinAngularFreqCh1
        function getSinAngularFreqCh1(app, event)
            app.g_sin_angular_freq_ch1 = app.EF_SinAngularFreqCh1.Value;
            
        end

        % Value changed function: EF_SinPhaseCh1
        function getSinPhaseCh1(app, event)
            app.g_sin_phase_ch1 = app.EF_SinPhaseCh1.Value;
            
        end

        % Button pushed function: BTN_SignalGenerateCh1
        function generateSinSignalCh1(app, event)
            channel1_sin_signal = userGenerateSinSignalCh1(app);
            app.g_signal_ch1 = channel1_sin_signal;
            plot(app.FIG_Ch1,app.g_signal_t,channel1_sin_signal)
        end

        % Value changed function: EF_SinAmplitudeCh2
        function getSinAmplitudeCh2(app, event)
            app.g_sin_amplitude_ch2 = app.EF_SinAmplitudeCh2.Value;
            
        end

        % Value changed function: EF_SinAngularFreqCh2
        function getSinAngularFreqCh2(app, event)
            app.g_sin_angular_freq_ch2 = app.EF_SinAngularFreqCh2.Value;
            
        end

        % Value changed function: EF_SinPhaseCh2
        function getSinPhaseCh2(app, event)
            app.g_sin_phase_ch2 = app.EF_SinPhaseCh2.Value;
            
        end

        % Button pushed function: BTN_SignalGenerateCh2
        function generateSinSignalCh2(app, event)
            channal2_sin_signal = userGenerateSinSignalCh2(app);
            app.g_signal_ch2 = channal2_sin_signal;
            plot(app.FIG_Ch2,app.g_signal_t,channal2_sin_signal)
        end

        % Button pushed function: BTN_CalcuateSignal
        function Signalplus(app, event)
            output_signal = userCalcuateMethod(app);
            app.g_output_signal = output_signal;
            if (app.g_calcuate_method == 4)
                plot(app.FIG_Output,app.g_signal_conv_t,app.g_output_signal);
            else
                plot(app.FIG_Output,app.g_signal_t,output_signal)
            end
        end

        % Value changed function: DP_CalcuateMethod
        function getCalcuateMethod(app, event)
            calcuate_method = app.DP_CalcuateMethod.Value;
            switch calcuate_method
                case '加'
                    app.g_calcuate_method = 0;
                case '减'
                    app.g_calcuate_method = 1;
                case '乘'
                    app.g_calcuate_method = 2;
                case '除'
                    app.g_calcuate_method = 3;
                case '卷积'
                    app.g_calcuate_method = 4;
            end
        end

        % Value changed function: DP_FreqAnalyzerSignalSource
        function freqGetSignalSource(app, event)
            signal_source = app.DP_FreqAnalyzerSignalSource.Value;
            switch signal_source
                case '音频信号'
                    app.g_signal_source = 0;
                case '信号发生器'
                    app.g_signal_source = 1;
            end
        end

        % Button pushed function: BTN_FreqAnalyzerInputSignal
        function freqSignalInput(app, event)
            switch app.g_signal_source
                case 0
                    plot(app.FIG_FreqAnalyzerOrignalSignal,app.g_audio_data)
                case 1
                    if (app.g_calcuate_method == 4)
                        plot(app.FIG_FreqAnalyzerOrignalSignal,app.g_signal_conv_t,app.g_output_signal);
                    else
                        plot(app.FIG_FreqAnalyzerOrignalSignal,app.g_signal_t,app.g_output_signal)
                    end
            end
        end

        % Button pushed function: BTN_FreqAnalyzerStartAnalyze
        function freqDomain(app, event)
%             userFFTShift(app);
            userCalcuatePowerSpectrum(app);
            userCalcuateAmplitudeSpectrum(app);
            userCalcuatePhaseSpectrum(app);
            userCalcueateReal(app);
            userCalcuateImag(app);
        end

        % Value changed function: EF_SquareAmplitudeCh1
        function getSquareAmplitudeCh1(app, event)
            app.g_square_amplitude_ch1 = app.EF_SquareAmplitudeCh1.Value;
        end

        % Value changed function: EF_SquareAngularFreqCh1
        function getSquareFreqCh1(app, event)
            app.g_square_freq_ch1 = app.EF_SquareAngularFreqCh1.Value;
            
        end

        % Button pushed function: BTN_SquareGenerateCh1
        function generateSquareSignalCh1(app, event)
            channel1_square_signal = userGenerateSquareSignalCh1(app);
            app.g_signal_ch1 = channel1_square_signal;
            plot(app.FIG_Ch1,app.g_signal_t,channel1_square_signal);
        end

        % Value changed function: EF_SquareAmplitudeCh2
        function getSquareAmplitudeCh2(app, event)
            app.g_square_amplitude_ch2 = app.EF_SquareAmplitudeCh2.Value;
            
        end

        % Value changed function: EF_SquareAngularFreqCh2
        function getSquareFreqCh2(app, event)
            app.g_square_freq_ch2 = app.EF_SquareAngularFreqCh2.Value;
            
        end

        % Button pushed function: BTN_SquareGenerateCh2
        function generateSquareSignalCh2(app, event)
            channel2_square_signal = userGenerateSquareSignalCh2(app);
            app.g_signal_ch2 = channel2_square_signal;
            plot(app.FIG_Ch2,app.g_signal_t,channel2_square_signal);
        end

        % Callback function
        function getSquarePhaseCh1(app, event)
            app.g_square_phase_ch1 = app.EF_SquarePhaseCh1.Value;
            
        end

        % Callback function
        function getSquarePhaseCh2(app, event)
            app.g_square_phase_ch2 = app.EF_SquarePhaseCh2.Value;
            
        end

        % Value changed function: EF_StepAmplitudeCh1
        function getStepAmplitudeCh1(app, event)
            app.g_step_amplitude_ch1 = app.EF_StepAmplitudeCh1.Value;
            
        end

        % Value changed function: EF_StepTimeCh1
        function getStepTimeCh1(app, event)
            app.g_step_time_ch1 = app.EF_StepTimeCh1.Value;
            
        end

        % Button pushed function: BTN_StepGenerateCh1
        function generateStepSignalCh1(app, event)
            channel1_step_signal = userGenerateStepSignalCh1(app);
            app.g_signal_ch1 = channel1_step_signal;
            plot(app.FIG_Ch1,app.g_signal_t,channel1_step_signal);
        end

        % Button pushed function: BTN_StepGenerateCh2
        function generateStepSignalCh2(app, event)
            channel2_step_signal = userGenerateStepSignalCh2(app);
            app.g_signal_ch2 = channel2_step_signal;
            plot(app.FIG_Ch2,app.g_signal_t,channel2_step_signal);
        end

        % Value changed function: EF_StepAmplitudeCh2
        function getStepAmplitudeCh2(app, event)
            app.g_step_amplitude_ch2 = app.EF_StepAmplitudeCh2.Value;
            
        end

        % Value changed function: EF_StepTimeCh2
        function getStepTimeCh2(app, event)
            app.g_step_time_ch2 = app.EF_StepTimeCh2.Value;
            
        end

        % Value changed function: EF_ChirpAmplitudeCh1
        function getChripAmplitudeCh1(app, event)
            app.g_chirp_amplitude_ch1 = app.EF_ChirpAmplitudeCh1.Value;
            
        end

        % Value changed function: EF_BandWidthCh1
        function getChripBandwidthCh1(app, event)
            app.g_chirp_bandwidth_ch1 = app.EF_BandWidthCh1.Value;
            
        end

        % Value changed function: EF_StartFrequenceCh1
        function getChripStartFreqCh1(app, event)
            app.g_chirp_start_freq_ch1 = app.EF_StartFrequenceCh1.Value;
            
        end

        % Value changed function: EF_StopFrequenceCh1
        function getChripStopFreqCh1(app, event)
            app.g_chirp_stop_freq_ch1 = app.EF_StopFrequenceCh1.Value;
            
        end

        % Button pushed function: BTN_ChirpGenerateCh1
        function generateChirpSignalCh1(app, event)
            channel1_chirp_signal = userGenerateChirpSignalCh1(app);
            app.g_signal_ch1 = channel1_chirp_signal;
            plot(app.FIG_Ch1,app.g_signal_t,channel1_chirp_signal);
        end

        % Value changed function: EF_ChripAmplitudeCh2
        function getChripAmplitudeCh2(app, event)
            app.g_chirp_amplitude_ch2 = app.EF_ChripAmplitudeCh2.Value;
            
        end

        % Value changed function: EF_BandWidthCh2
        function getChripBandwidthCh2(app, event)
            app.g_chirp_bandwdth_ch2 = app.EF_BandWidthCh2.Value;
            
        end

        % Value changed function: EF_StartFrequenceCh2
        function getChripStartFreqCh2(app, event)
            app.g_chirp_start_freq_ch2 = app.EF_StartFrequenceCh2.Value;
            
        end

        % Value changed function: EF_StopFrequenceCh2
        function getChripStopFreqCh2(app, event)
            app.g_chirp_stop_freq_ch2 = app.EF_StopFrequenceCh2.Value;
            
        end

        % Button pushed function: BTN_ChripGenerateCh2
        function generateChirpSignalCh2(app, event)
            channel2_chirp_signal = userGenerateChirpSignalCh2(app);
            app.g_signal_ch2 = channel2_chirp_signal;
            plot(app.FIG_Ch2,app.g_signal_t,channel2_chirp_signal);
        end

        % Menu selected function: MENU_Exit
        function exitApp(app, event)
            delete(app.UIFigure) % Exit the application
        end

        % Value changed function: DP_TimeAnalyzerSignalSource
        function timeGetSignalSource(app, event)
            signal_source = app.DP_TimeAnalyzerSignalSource.Value;
            switch signal_source
                case '音频信号'
                    app.g_signal_source = 0;
                case '信号发生器'
                    app.g_signal_source = 1;
            end
        end

        % Button pushed function: BTN_TimeAnalyzerInputSignal
        function timeSignalInput(app, event)
            switch app.g_signal_source
                case 0
                    plot(app.FIG_TimeAnalyzerTimeDomain,app.g_audio_data);
                case 1
                    if (app.g_calcuate_method == 4)
                        plot(app.FIG_TimeAnalyzerTimeDomain,app.g_signal_conv_t,app.g_output_signal);
                    else
                        plot(app.FIG_TimeAnalyzerTimeDomain,app.g_signal_t,app.g_output_signal);
                    end
            end
        end

        % Value changed function: Time_PeakPeakValue
        function peakPeakValue(app, event)
            
            app.Time_PeakPeakValue.Value = num2str(app.g_peak_peak_value);

        end

        % Button pushed function: BTN_TimeAnalyzerDimensionAnalyze
        function startDimensionAnalyze(app, event)
            peak_peak_value = userCalcuatePeakPeakValue(app);
            mean_value = userCalcuateMean(app);
            var_value = userCalcuateVar(app);
            std_value = userCalcuateStdValue(app);
            rms_value = userCalcuateRmsValue(app);
            peak_value = userCalcuatePeakValue(app);
            period_value = userCalcuatePeriodValue(app);

            app.g_peak_peak_value = peak_peak_value;
            app.g_mean_value = mean_value;
            app.g_var_value = var_value;
            app.g_std_value = std_value;
            app.g_rms_value = rms_value;
            app.g_peak_value = peak_value;
            app.g_period_value = period_value;

            peakPeakValue(app);
            meanValue(app)
            varValue(app);
            stdValue(app);
            rmsValue(app);
            peakValue(app);
            periodValue(app);
        end

        % Value changed function: Time_MeanValue
        function meanValue(app, event)
            app.Time_MeanValue.Value = num2str(app.g_mean_value);
            
        end

        % Value changed function: Time_VarValue
        function varValue(app, event)
            app.Time_VarValue.Value = num2str(app.g_var_value);
            
        end

        % Value changed function: Time_StdValue
        function stdValue(app, event)
            app.Time_StdValue.Value = num2str(app.g_std_value);
            
        end

        % Value changed function: Time_RmsValue
        function rmsValue(app, event)
            app.Time_RmsValue.Value = num2str(app.g_rms_value);
            
        end

        % Value changed function: Time_PeakValue
        function peakValue(app, event)
            app.Time_PeakValue.Value = num2str(app.g_peak_value);
            
        end

        % Value changed function: Time_CrestFactorValue
        function crestFactorValue(app, event)
            app.Time_CrestFactorValue.Value = num2str(app.g_crest_factor_value);
            
        end

        % Value changed function: Time_ShapeValue
        function shapeValue(app, event)
            app.Time_ShapeValue.Value = num2str(app.g_shape_value);
            
        end

        % Value changed function: Time_ImpulseValue
        function impulseValue(app, event)
            app.Time_ImpulseValue.Value = num2str(app.g_impulse_value);
            
        end

        % Value changed function: Time_ClearanceFactorValue
        function clearanceFactorValue(app, event)
            app.Time_ClearanceFactorValue.Value = num2str(app.g_clearance_factor_value);
            
        end

        % Button pushed function: BTN_TimeAnalyzerDimensionlessAnalyze
        function startDimensionlessAnalyze(app, event)
            crest_value = userCalcuateCrestFactorValue(app);
            shape_value = userCalcuateShapeValue(app);
            impulse_value = userCalcuateImpulseValue(app);
            clearance_value = userCalcuateClearanceValue(app);
            app.g_crest_factor_value = crest_value;
            app.g_shape_value = shape_value;
            app.g_impulse_value = impulse_value;
            app.g_clearance_factor_value = clearance_value;
            crestFactorValue(app);
            shapeValue(app);
            impulseValue(app);
            clearanceFactorValue(app);
        end

        % Value changed function: Time_PeriodValue
        function periodValue(app, event)
            app.Time_PeriodValue.Value = num2str(app.g_period_value);
            
        end

        % Value changed function: DP_FFTSignalSource
        function fftSignalSource(app, event)
            signal_source = app.DP_TimeAnalyzerSignalSource.Value;
            switch signal_source
                case '音频信号'
                    app.g_signal_source = 0;
                case '信号发生器'
                    app.g_signal_source = 1;
            end
        end

        % Button pushed function: BTN_FFTSignalIntput
        function fftSignalInput(app, event)
            switch app.g_signal_source
                case 0
                    plot(app.FIG_FFTOrignal,app.g_audio_data)
                case 1
                    if (app.g_calcuate_method == 4)
                        plot(app.FIG_FFTOrignal,app.g_signal_conv_t,app.g_output_signal);
                    else
                        plot(app.FIG_FFTOrignal,app.g_signal_t,app.g_output_signal);
                    end
            end
        end

        % Button pushed function: BTN_StartFFT
        function fftTransfer(app, event)
            userCalcuateFFTPara(app);
        end

        % Value changed function: DP_FFTOvertone
        function fftGetOvertone(app, event)
            value = app.DP_FFTOvertone.Value;
            app.g_fft_overtone_value = value;
        end

        % Value changed function: ED_PassFreq
        function getFilterPassFreq(app, event)
            value = app.ED_PassFreq.Value;
            app.g_filter_pass_freq = value;
            
        end

        % Value changed function: ED_CutFreq
        function getFilterCutFreq(app, event)
            value = app.ED_CutFreq.Value;
            app.g_filter_cut_freq = value;
        end

        % Value changed function: DP_Filter
        function getFilterType(app, event)
            value = app.DP_Filter.Value;
            switch value
                case '低通'
                    app.g_filter_type = 0;
                case '高通'
                    app.g_filter_type = 1;
            end
        end

        % Callback function: not associated with a component
        function getFilterOrder(app, event)
            value = app.EditField.Value;
            app.g_filter_order = value;
        end

        % Button pushed function: BTN_InputSignal
        function filterSignalInput(app, event)
            filter_input_signal = app.g_output_signal;
            plot(app.FIG_FilterInputSignal,filter_input_signal);
        end

        % Button pushed function: BTN_StartFilter
        function startFilter(app, event)
            filterStart(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'turbo');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '菜单';
            app.MENU_Exit = uimenu(app.Menu);
            app.MENU_Exit.MenuSelectedFcn = createCallbackFcn(app, @exitApp, true);
            app.MENU_Exit.Text = '退出';
            app.About = uimenu(app.UIFigure);
            app.About.Text = '关于';
            app.TABG_Audio = uitabgroup(app.UIFigure);
            app.TAB_AudioAnalyzer = uitab(app.TABG_Audio);
            app.TAB_AudioAnalyzer.Title = '音频信号分析';
            app.FIG_Left = uiaxes(app.TAB_AudioAnalyzer);
            title(app.FIG_Left, '左声道')
            xlabel(app.FIG_Left, '时间')
            ylabel(app.FIG_Left, '响度')
            zlabel(app.FIG_Left, 'Z')
            app.FIG_Right = uiaxes(app.TAB_AudioAnalyzer);
            title(app.FIG_Right, '右声道')
            xlabel(app.FIG_Right, '时间')
            ylabel(app.FIG_Right, '响度')
            zlabel(app.FIG_Right, 'Z')
            app.Label = uilabel(app.TAB_AudioAnalyzer);
            app.Label.Text = '输入设备';
            app.DP_InputDeivce = uidropdown(app.TAB_AudioAnalyzer);
            app.DP_InputDeivce.Items = {'Audio Device 1'};
            app.DP_InputDeivce.ValueChangedFcn = createCallbackFcn(app, @getInputDevice, true);
            app.DP_InputDeivce.Value = 'Audio Device 1';
            app.Label_2 = uilabel(app.TAB_AudioAnalyzer);
            app.Label_2.Text = '采样深度';
            app.DP_SimplingDepth = uidropdown(app.TAB_AudioAnalyzer);
            app.DP_SimplingDepth.Items = {'8bit', '16bit', '24bit', '32bit'};
            app.DP_SimplingDepth.ValueChangedFcn = createCallbackFcn(app, @getSimplingDepth, true);
            app.DP_SimplingDepth.Value = '16bit';
            app.Label_3 = uilabel(app.TAB_AudioAnalyzer);
            app.Label_3.Text = '采样频率';
            app.DP_SimplingFreq = uidropdown(app.TAB_AudioAnalyzer);
            app.DP_SimplingFreq.Items = {'44100HZ'};
            app.DP_SimplingFreq.ValueChangedFcn = createCallbackFcn(app, @getSimplingFreq, true);
            app.DP_SimplingFreq.Value = '44100HZ';
            app.Label_4 = uilabel(app.TAB_AudioAnalyzer);
            app.Label_4.Text = '采样时间';
            app.DP_SimplingTime = uidropdown(app.TAB_AudioAnalyzer);
            app.DP_SimplingTime.Items = {'5s', '10s', '15s', '20s'};
            app.DP_SimplingTime.ValueChangedFcn = createCallbackFcn(app, @getSimplingTime, true);
            app.DP_SimplingTime.Value = '5s';
            app.BTN_StartSimpling = uibutton(app.TAB_AudioAnalyzer, 'push');
            app.BTN_StartSimpling.ButtonPushedFcn = createCallbackFcn(app, @startSimpling, true);
            app.BTN_StartSimpling.Text = '开始采样';
            app.BTN_PlayAduio = uibutton(app.TAB_AudioAnalyzer, 'push');
            app.BTN_PlayAduio.ButtonPushedFcn = createCallbackFcn(app, @playAudio, true);
            app.BTN_PlayAduio.Text = '播放声音';
            app.BTN_PlotChannelData = uibutton(app.TAB_AudioAnalyzer, 'push');
            app.BTN_PlotChannelData.ButtonPushedFcn = createCallbackFcn(app, @PlotChannelData, true);
            app.BTN_PlotChannelData.Text = '绘制左右声道图像';
            app.L_SimplingPara = uilabel(app.TAB_AudioAnalyzer);
            app.L_SimplingPara.Text = '采样参数';
            app.L_AudioAnalyze = uilabel(app.TAB_AudioAnalyzer);
            app.L_AudioAnalyze.Text = '音频分析';
            app.L_AnalyzerFig = uilabel(app.TAB_AudioAnalyzer);
            app.L_AnalyzerFig.Text = '绘图区';
            app.TABG_Generator = uitab(app.TABG_Audio);
            app.TABG_Generator.Title = '信号发生器';
            app.FIG_Ch1 = uiaxes(app.TABG_Generator);
            title(app.FIG_Ch1, '通道1')
            xlabel(app.FIG_Ch1, '时间')
            ylabel(app.FIG_Ch1, '幅度')
            zlabel(app.FIG_Ch1, 'Z')
            app.FIG_Ch2 = uiaxes(app.TABG_Generator);
            title(app.FIG_Ch2, '通道2')
            xlabel(app.FIG_Ch2, '时间')
            ylabel(app.FIG_Ch2, '幅度')
            zlabel(app.FIG_Ch2, 'Z')
            app.FIG_Output = uiaxes(app.TABG_Generator);
            title(app.FIG_Output, '输出信号')
            xlabel(app.FIG_Output, '时间')
            ylabel(app.FIG_Output, '幅度')
            zlabel(app.FIG_Output, 'Z')
            app.TabGroup2 = uitabgroup(app.TABG_Generator);
            app.TAB_SinCh1 = uitab(app.TabGroup2);
            app.TAB_SinCh1.Title = '正弦信号';
            app.Label_8 = uilabel(app.TAB_SinCh1);
            app.Label_8.Text = '幅度';
            app.EF_SinAmplitudeCh1 = uieditfield(app.TAB_SinCh1, 'numeric');
            app.EF_SinAmplitudeCh1.ValueChangedFcn = createCallbackFcn(app, @getSinAmplitudeCh1, true);
            app.EditField_2Label = uilabel(app.TAB_SinCh1);
            app.EditField_2Label.Text = '角频率';
            app.EF_SinAngularFreqCh1 = uieditfield(app.TAB_SinCh1, 'numeric');
            app.EF_SinAngularFreqCh1.ValueChangedFcn = createCallbackFcn(app, @getSinAngularFreqCh1, true);
            app.EditField_3Label = uilabel(app.TAB_SinCh1);
            app.EditField_3Label.Text = '相位';
            app.EF_SinPhaseCh1 = uieditfield(app.TAB_SinCh1, 'numeric');
            app.EF_SinPhaseCh1.ValueChangedFcn = createCallbackFcn(app, @getSinPhaseCh1, true);
            app.BTN_SignalGenerateCh1 = uibutton(app.TAB_SinCh1, 'push');
            app.BTN_SignalGenerateCh1.ButtonPushedFcn = createCallbackFcn(app, @generateSinSignalCh1, true);
            app.BTN_SignalGenerateCh1.Text = '绘图';
            app.TAB_SquareCh1 = uitab(app.TabGroup2);
            app.TAB_SquareCh1.Title = '方波信号';
            app.Label_11 = uilabel(app.TAB_SquareCh1);
            app.Label_11.Text = '幅度';
            app.EF_SquareAmplitudeCh1 = uieditfield(app.TAB_SquareCh1, 'numeric');
            app.EF_SquareAmplitudeCh1.ValueChangedFcn = createCallbackFcn(app, @getSquareAmplitudeCh1, true);
            app.EditField_2Label_2 = uilabel(app.TAB_SquareCh1);
            app.EditField_2Label_2.Text = '频率';
            app.EF_SquareAngularFreqCh1 = uieditfield(app.TAB_SquareCh1, 'numeric');
            app.EF_SquareAngularFreqCh1.ValueChangedFcn = createCallbackFcn(app, @getSquareFreqCh1, true);
            app.BTN_SquareGenerateCh1 = uibutton(app.TAB_SquareCh1, 'push');
            app.BTN_SquareGenerateCh1.ButtonPushedFcn = createCallbackFcn(app, @generateSquareSignalCh1, true);
            app.BTN_SquareGenerateCh1.Text = '绘图';
            app.TAB_StepCh1 = uitab(app.TabGroup2);
            app.TAB_StepCh1.Title = '阶跃信号';
            app.Label_13 = uilabel(app.TAB_StepCh1);
            app.Label_13.Text = '幅度';
            app.EF_StepAmplitudeCh1 = uieditfield(app.TAB_StepCh1, 'numeric');
            app.EF_StepAmplitudeCh1.ValueChangedFcn = createCallbackFcn(app, @getStepAmplitudeCh1, true);
            app.Label_14 = uilabel(app.TAB_StepCh1);
            app.Label_14.Text = '突变时间';
            app.EF_StepTimeCh1 = uieditfield(app.TAB_StepCh1, 'numeric');
            app.EF_StepTimeCh1.ValueChangedFcn = createCallbackFcn(app, @getStepTimeCh1, true);
            app.BTN_StepGenerateCh1 = uibutton(app.TAB_StepCh1, 'push');
            app.BTN_StepGenerateCh1.ButtonPushedFcn = createCallbackFcn(app, @generateStepSignalCh1, true);
            app.BTN_StepGenerateCh1.Text = '绘图';
            app.TAB_ChirpCh1 = uitab(app.TabGroup2);
            app.TAB_ChirpCh1.Title = 'Chrip信号';
            app.BTN_ChirpGenerateCh1 = uibutton(app.TAB_ChirpCh1, 'push');
            app.BTN_ChirpGenerateCh1.ButtonPushedFcn = createCallbackFcn(app, @generateChirpSignalCh1, true);
            app.BTN_ChirpGenerateCh1.Text = '绘图';
            app.Label_17 = uilabel(app.TAB_ChirpCh1);
            app.Label_17.Text = '幅度';
            app.EF_ChirpAmplitudeCh1 = uieditfield(app.TAB_ChirpCh1, 'numeric');
            app.EF_ChirpAmplitudeCh1.ValueChangedFcn = createCallbackFcn(app, @getChripAmplitudeCh1, true);
            app.EditField_2Label_4 = uilabel(app.TAB_ChirpCh1);
            app.EditField_2Label_4.Text = '带宽';
            app.EF_BandWidthCh1 = uieditfield(app.TAB_ChirpCh1, 'numeric');
            app.EF_BandWidthCh1.ValueChangedFcn = createCallbackFcn(app, @getChripBandwidthCh1, true);
            app.EditField_3Label_4 = uilabel(app.TAB_ChirpCh1);
            app.EditField_3Label_4.Text = '起始频率';
            app.EF_StartFrequenceCh1 = uieditfield(app.TAB_ChirpCh1, 'numeric');
            app.EF_StartFrequenceCh1.ValueChangedFcn = createCallbackFcn(app, @getChripStartFreqCh1, true);
            app.EditField_3Label_5 = uilabel(app.TAB_ChirpCh1);
            app.EditField_3Label_5.Text = '截止频率';
            app.EF_StopFrequenceCh1 = uieditfield(app.TAB_ChirpCh1, 'numeric');
            app.EF_StopFrequenceCh1.ValueChangedFcn = createCallbackFcn(app, @getChripStopFreqCh1, true);
            app.TABG_Ch2 = uitabgroup(app.TABG_Generator);
            app.TAB_SinCh2 = uitab(app.TABG_Ch2);
            app.TAB_SinCh2.Title = '正弦信号';
            app.BTN_SignalGenerateCh2 = uibutton(app.TAB_SinCh2, 'push');
            app.BTN_SignalGenerateCh2.ButtonPushedFcn = createCallbackFcn(app, @generateSinSignalCh2, true);
            app.BTN_SignalGenerateCh2.Text = '绘图';
            app.EditField_4Label = uilabel(app.TAB_SinCh2);
            app.EditField_4Label.Text = '幅度';
            app.EF_SinAmplitudeCh2 = uieditfield(app.TAB_SinCh2, 'numeric');
            app.EF_SinAmplitudeCh2.ValueChangedFcn = createCallbackFcn(app, @getSinAmplitudeCh2, true);
            app.EditField_5Label = uilabel(app.TAB_SinCh2);
            app.EditField_5Label.Text = '角频率';
            app.EF_SinAngularFreqCh2 = uieditfield(app.TAB_SinCh2, 'numeric');
            app.EF_SinAngularFreqCh2.ValueChangedFcn = createCallbackFcn(app, @getSinAngularFreqCh2, true);
            app.EditField_6Label = uilabel(app.TAB_SinCh2);
            app.EditField_6Label.Text = '相位';
            app.EF_SinPhaseCh2 = uieditfield(app.TAB_SinCh2, 'numeric');
            app.EF_SinPhaseCh2.ValueChangedFcn = createCallbackFcn(app, @getSinPhaseCh2, true);
            app.TAB_SquareCh2 = uitab(app.TABG_Ch2);
            app.TAB_SquareCh2.Title = '方波信号';
            app.BTN_SquareGenerateCh2 = uibutton(app.TAB_SquareCh2, 'push');
            app.BTN_SquareGenerateCh2.ButtonPushedFcn = createCallbackFcn(app, @generateSquareSignalCh2, true);
            app.BTN_SquareGenerateCh2.Text = '绘图';
            app.Label_12 = uilabel(app.TAB_SquareCh2);
            app.Label_12.Text = '幅度';
            app.EF_SquareAmplitudeCh2 = uieditfield(app.TAB_SquareCh2, 'numeric');
            app.EF_SquareAmplitudeCh2.ValueChangedFcn = createCallbackFcn(app, @getSquareAmplitudeCh2, true);
            app.EditField_2Label_3 = uilabel(app.TAB_SquareCh2);
            app.EditField_2Label_3.Text = '频率';
            app.EF_SquareAngularFreqCh2 = uieditfield(app.TAB_SquareCh2, 'numeric');
            app.EF_SquareAngularFreqCh2.ValueChangedFcn = createCallbackFcn(app, @getSquareFreqCh2, true);
            app.TAB_StepCH2 = uitab(app.TABG_Ch2);
            app.TAB_StepCH2.Title = '阶跃信号';
            app.BTN_StepGenerateCh2 = uibutton(app.TAB_StepCH2, 'push');
            app.BTN_StepGenerateCh2.ButtonPushedFcn = createCallbackFcn(app, @generateStepSignalCh2, true);
            app.BTN_StepGenerateCh2.Text = '绘图';
            app.Label_15 = uilabel(app.TAB_StepCH2);
            app.Label_15.Text = '幅度';
            app.EF_StepAmplitudeCh2 = uieditfield(app.TAB_StepCH2, 'numeric');
            app.EF_StepAmplitudeCh2.ValueChangedFcn = createCallbackFcn(app, @getStepAmplitudeCh2, true);
            app.Label_16 = uilabel(app.TAB_StepCH2);
            app.Label_16.Text = '突变时间';
            app.EF_StepTimeCh2 = uieditfield(app.TAB_StepCH2, 'numeric');
            app.EF_StepTimeCh2.ValueChangedFcn = createCallbackFcn(app, @getStepTimeCh2, true);
            app.TAB_ChirpCh2 = uitab(app.TABG_Ch2);
            app.TAB_ChirpCh2.Title = 'Chrip信号';
            app.BTN_ChripGenerateCh2 = uibutton(app.TAB_ChirpCh2, 'push');
            app.BTN_ChripGenerateCh2.ButtonPushedFcn = createCallbackFcn(app, @generateChirpSignalCh2, true);
            app.BTN_ChripGenerateCh2.Text = '绘图';
            app.EditField_2Label_5 = uilabel(app.TAB_ChirpCh2);
            app.EditField_2Label_5.Text = '带宽';
            app.EF_BandWidthCh2 = uieditfield(app.TAB_ChirpCh2, 'numeric');
            app.EF_BandWidthCh2.ValueChangedFcn = createCallbackFcn(app, @getChripBandwidthCh2, true);
            app.EditField_3Label_6 = uilabel(app.TAB_ChirpCh2);
            app.EditField_3Label_6.Text = '起始频率';
            app.EF_StartFrequenceCh2 = uieditfield(app.TAB_ChirpCh2, 'numeric');
            app.EF_StartFrequenceCh2.ValueChangedFcn = createCallbackFcn(app, @getChripStartFreqCh2, true);
            app.EF_ChripAmplitudeCh2 = uieditfield(app.TAB_ChirpCh2, 'numeric');
            app.EF_ChripAmplitudeCh2.ValueChangedFcn = createCallbackFcn(app, @getChripAmplitudeCh2, true);
            app.Label_18 = uilabel(app.TAB_ChirpCh2);
            app.Label_18.Text = '幅度';
            app.EditField_3Label_7 = uilabel(app.TAB_ChirpCh2);
            app.EditField_3Label_7.Text = '截止频率';
            app.EF_StopFrequenceCh2 = uieditfield(app.TAB_ChirpCh2, 'numeric');
            app.EF_StopFrequenceCh2.ValueChangedFcn = createCallbackFcn(app, @getChripStopFreqCh2, true);
            app.BTN_CalcuateSignal = uibutton(app.TABG_Generator, 'push');
            app.BTN_CalcuateSignal.ButtonPushedFcn = createCallbackFcn(app, @Signalplus, true);
            app.BTN_CalcuateSignal.Text = '运算';
            app.Label_9 = uilabel(app.TABG_Generator);
            app.Label_9.Text = '信号运算';
            app.DP_CalcuateMethod = uidropdown(app.TABG_Generator);
            app.DP_CalcuateMethod.Items = {'加', '减', '乘', '除', '卷积'};
            app.DP_CalcuateMethod.ValueChangedFcn = createCallbackFcn(app, @getCalcuateMethod, true);
            app.DP_CalcuateMethod.Value = '加';
            app.TimeAnalyzer = uitab(app.TABG_Audio);
            app.TimeAnalyzer.Title = '时域分析';
            app.FIG_TimeAnalyzerTimeDomain = uiaxes(app.TimeAnalyzer);
            title(app.FIG_TimeAnalyzerTimeDomain, '时域图')
            xlabel(app.FIG_TimeAnalyzerTimeDomain, '时间')
            ylabel(app.FIG_TimeAnalyzerTimeDomain, '振幅')
            zlabel(app.FIG_TimeAnalyzerTimeDomain, 'Z')
            app.BTN_TimeAnalyzerInputSignal = uibutton(app.TimeAnalyzer, 'push');
            app.BTN_TimeAnalyzerInputSignal.ButtonPushedFcn = createCallbackFcn(app, @timeSignalInput, true);
            app.BTN_TimeAnalyzerInputSignal.Text = '输入信号';
            app.BTN_TimeAnalyzerDimensionAnalyze = uibutton(app.TimeAnalyzer, 'push');
            app.BTN_TimeAnalyzerDimensionAnalyze.ButtonPushedFcn = createCallbackFcn(app, @startDimensionAnalyze, true);
            app.BTN_TimeAnalyzerDimensionAnalyze.Text = '计算有量纲指标';
            app.Label_19 = uilabel(app.TimeAnalyzer);
            app.Label_19.Text = '信号源';
            app.DP_TimeAnalyzerSignalSource = uidropdown(app.TimeAnalyzer);
            app.DP_TimeAnalyzerSignalSource.Items = {'音频信号', '信号发生器', 'Option 3', 'Option 4'};
            app.DP_TimeAnalyzerSignalSource.ValueChangedFcn = createCallbackFcn(app, @timeGetSignalSource, true);
            app.DP_TimeAnalyzerSignalSource.Value = '信号发生器';
            app.Label_20 = uilabel(app.TimeAnalyzer);
            app.Label_20.Text = '峰峰值';
            app.Time_PeakPeakValue = uitextarea(app.TimeAnalyzer);
            app.Time_PeakPeakValue.ValueChangedFcn = createCallbackFcn(app, @peakPeakValue, true);
            app.Label_21 = uilabel(app.TimeAnalyzer);
            app.Label_21.Text = '均值';
            app.Time_MeanValue = uitextarea(app.TimeAnalyzer);
            app.Time_MeanValue.ValueChangedFcn = createCallbackFcn(app, @meanValue, true);
            app.Label_22 = uilabel(app.TimeAnalyzer);
            app.Label_22.Text = '方差';
            app.Time_VarValue = uitextarea(app.TimeAnalyzer);
            app.Time_VarValue.ValueChangedFcn = createCallbackFcn(app, @varValue, true);
            app.Label_23 = uilabel(app.TimeAnalyzer);
            app.Label_23.Text = '标准差';
            app.Time_StdValue = uitextarea(app.TimeAnalyzer);
            app.Time_StdValue.ValueChangedFcn = createCallbackFcn(app, @stdValue, true);
            app.Label_24 = uilabel(app.TimeAnalyzer);
            app.Label_24.Text = '均方根';
            app.Time_RmsValue = uitextarea(app.TimeAnalyzer);
            app.Time_RmsValue.ValueChangedFcn = createCallbackFcn(app, @rmsValue, true);
            app.Label_25 = uilabel(app.TimeAnalyzer);
            app.Label_25.Text = '峰值';
            app.Time_PeakValue = uitextarea(app.TimeAnalyzer);
            app.Time_PeakValue.ValueChangedFcn = createCallbackFcn(app, @peakValue, true);
            app.Label_26 = uilabel(app.TimeAnalyzer);
            app.Label_26.Text = '峰值因子';
            app.Time_CrestFactorValue = uitextarea(app.TimeAnalyzer);
            app.Time_CrestFactorValue.ValueChangedFcn = createCallbackFcn(app, @crestFactorValue, true);
            app.Label_27 = uilabel(app.TimeAnalyzer);
            app.Label_27.Text = '波形因子';
            app.Time_ShapeValue = uitextarea(app.TimeAnalyzer);
            app.Time_ShapeValue.ValueChangedFcn = createCallbackFcn(app, @shapeValue, true);
            app.Label_28 = uilabel(app.TimeAnalyzer);
            app.Label_28.Text = '脉冲因子';
            app.Time_ImpulseValue = uitextarea(app.TimeAnalyzer);
            app.Time_ImpulseValue.ValueChangedFcn = createCallbackFcn(app, @impulseValue, true);
            app.Label_29 = uilabel(app.TimeAnalyzer);
            app.Label_29.Text = '裕度因子';
            app.Time_ClearanceFactorValue = uitextarea(app.TimeAnalyzer);
            app.Time_ClearanceFactorValue.ValueChangedFcn = createCallbackFcn(app, @clearanceFactorValue, true);
            app.BTN_TimeAnalyzerDimensionlessAnalyze = uibutton(app.TimeAnalyzer, 'push');
            app.BTN_TimeAnalyzerDimensionlessAnalyze.ButtonPushedFcn = createCallbackFcn(app, @startDimensionlessAnalyze, true);
            app.BTN_TimeAnalyzerDimensionlessAnalyze.Text = '计算有量纲指标';
            app.Label_30 = uilabel(app.TimeAnalyzer);
            app.Label_30.Text = '周期';
            app.Time_PeriodValue = uitextarea(app.TimeAnalyzer);
            app.Time_PeriodValue.ValueChangedFcn = createCallbackFcn(app, @periodValue, true);
            app.FFT = uitab(app.TABG_Audio);
            app.FFT.Title = '傅利叶变换';
            app.FIG_FFT = uiaxes(app.FFT);
            title(app.FIG_FFT, '傅里叶变换拟合图')
            xlabel(app.FIG_FFT, '时间')
            ylabel(app.FIG_FFT, '振幅')
            zlabel(app.FIG_FFT, 'Z')
            app.FIG_FFTOrignal = uiaxes(app.FFT);
            title(app.FIG_FFTOrignal, '输入信号')
            xlabel(app.FIG_FFTOrignal, '时间')
            ylabel(app.FIG_FFTOrignal, '振幅')
            zlabel(app.FIG_FFTOrignal, 'Z')
            app.Label_31 = uilabel(app.FFT);
            app.Label_31.Text = '信号源';
            app.DP_FFTSignalSource = uidropdown(app.FFT);
            app.DP_FFTSignalSource.Items = {'音频信号', '信号发生器', 'Option 3', 'Option 4'};
            app.DP_FFTSignalSource.ValueChangedFcn = createCallbackFcn(app, @fftSignalSource, true);
            app.DP_FFTSignalSource.Value = '信号发生器';
            app.BTN_FFTSignalIntput = uibutton(app.FFT, 'push');
            app.BTN_FFTSignalIntput.ButtonPushedFcn = createCallbackFcn(app, @fftSignalInput, true);
            app.BTN_FFTSignalIntput.Text = '输入信号';
            app.BTN_StartFFT = uibutton(app.FFT, 'push');
            app.BTN_StartFFT.ButtonPushedFcn = createCallbackFcn(app, @fftTransfer, true);
            app.BTN_StartFFT.Text = '傅里叶变换';
            app.Label_32 = uilabel(app.FFT);
            app.Label_32.Text = '最大谐波次数';
            app.DP_FFTOvertone = uidropdown(app.FFT);
            app.DP_FFTOvertone.Items = {'1', '2', '3', '4', '5', '10', '20', '30', '40', '50', '100', '500', '1000'};
            app.DP_FFTOvertone.ValueChangedFcn = createCallbackFcn(app, @fftGetOvertone, true);
            app.DP_FFTOvertone.Value = '1';
            app.TABG_FrequenceAnalyzer = uitab(app.TABG_Audio);
            app.TABG_FrequenceAnalyzer.Title = '频域分析';
            app.FIG_FreqAnalyzerOrignalSignal = uiaxes(app.TABG_FrequenceAnalyzer);
            title(app.FIG_FreqAnalyzerOrignalSignal, '原信号')
            zlabel(app.FIG_FreqAnalyzerOrignalSignal, 'Z')
            app.FIG_FreqAnalyzerPowerSpectrum = uiaxes(app.TABG_FrequenceAnalyzer);
            title(app.FIG_FreqAnalyzerPowerSpectrum, '功率谱')
            zlabel(app.FIG_FreqAnalyzerPowerSpectrum, 'Z')
            app.FIG_FreqAnalyzerAmplitudeSpectrum = uiaxes(app.TABG_FrequenceAnalyzer);
            title(app.FIG_FreqAnalyzerAmplitudeSpectrum, '幅值谱')
            zlabel(app.FIG_FreqAnalyzerAmplitudeSpectrum, 'Z')
            app.FIG_FreqAnalyzerPhaseSpectrum = uiaxes(app.TABG_FrequenceAnalyzer);
            title(app.FIG_FreqAnalyzerPhaseSpectrum, '相位谱')
            zlabel(app.FIG_FreqAnalyzerPhaseSpectrum, 'Z')
            app.FIG_FreqAnalyzerImag = uiaxes(app.TABG_FrequenceAnalyzer);
            title(app.FIG_FreqAnalyzerImag, '虚频谱')
            zlabel(app.FIG_FreqAnalyzerImag, 'Z')
            app.FIG_FreqAnalyzerReal = uiaxes(app.TABG_FrequenceAnalyzer);
            title(app.FIG_FreqAnalyzerReal, '实频谱')
            zlabel(app.FIG_FreqAnalyzerReal, 'Z')
            app.Label_10 = uilabel(app.TABG_FrequenceAnalyzer);
            app.Label_10.Text = '信号源';
            app.DP_FreqAnalyzerSignalSource = uidropdown(app.TABG_FrequenceAnalyzer);
            app.DP_FreqAnalyzerSignalSource.Items = {'音频信号', '信号发生器', 'Option 3', 'Option 4'};
            app.DP_FreqAnalyzerSignalSource.ValueChangedFcn = createCallbackFcn(app, @freqGetSignalSource, true);
            app.DP_FreqAnalyzerSignalSource.Value = '信号发生器';
            app.BTN_FreqAnalyzerInputSignal = uibutton(app.TABG_FrequenceAnalyzer, 'push');
            app.BTN_FreqAnalyzerInputSignal.ButtonPushedFcn = createCallbackFcn(app, @freqSignalInput, true);
            app.BTN_FreqAnalyzerInputSignal.Text = '输入信号';
            app.BTN_FreqAnalyzerStartAnalyze = uibutton(app.TABG_FrequenceAnalyzer, 'push');
            app.BTN_FreqAnalyzerStartAnalyze.ButtonPushedFcn = createCallbackFcn(app, @freqDomain, true);
            app.BTN_FreqAnalyzerStartAnalyze.Text = '频域分析';
            app.TAB_Filter = uitab(app.TABG_Audio);
            app.TAB_Filter.Title = '滤波器';
            app.FIG_FilterInputSignal = uiaxes(app.TAB_Filter);
            title(app.FIG_FilterInputSignal, '输入信号')
            xlabel(app.FIG_FilterInputSignal, '时间')
            ylabel(app.FIG_FilterInputSignal, '振幅')
            zlabel(app.FIG_FilterInputSignal, 'Z')
            app.FIG_FiltedSignal = uiaxes(app.TAB_Filter);
            title(app.FIG_FiltedSignal, '滤波后信号')
            xlabel(app.FIG_FiltedSignal, '时间')
            ylabel(app.FIG_FiltedSignal, '振幅')
            zlabel(app.FIG_FiltedSignal, 'Z')
            app.Label_33 = uilabel(app.TAB_Filter);
            app.Label_33.Text = '通过频率';
            app.ED_PassFreq = uieditfield(app.TAB_Filter, 'numeric');
            app.ED_PassFreq.ValueChangedFcn = createCallbackFcn(app, @getFilterPassFreq, true);
            app.Label_34 = uilabel(app.TAB_Filter);
            app.Label_34.Text = '截止频率';
            app.ED_CutFreq = uieditfield(app.TAB_Filter, 'numeric');
            app.ED_CutFreq.ValueChangedFcn = createCallbackFcn(app, @getFilterCutFreq, true);
            app.Label_35 = uilabel(app.TAB_Filter);
            app.Label_35.Text = '滤波器类型';
            app.DP_Filter = uidropdown(app.TAB_Filter);
            app.DP_Filter.Items = {'低通', '高通'};
            app.DP_Filter.ValueChangedFcn = createCallbackFcn(app, @getFilterType, true);
            app.DP_Filter.Value = '低通';
            app.BTN_InputSignal = uibutton(app.TAB_Filter, 'push');
            app.BTN_InputSignal.ButtonPushedFcn = createCallbackFcn(app, @filterSignalInput, true);
            app.BTN_InputSignal.Text = '输入信号';
            app.BTN_StartFilter = uibutton(app.TAB_Filter, 'push');
            app.BTN_StartFilter.ButtonPushedFcn = createCallbackFcn(app, @startFilter, true);
            app.BTN_StartFilter.Text = '滤波';
            app.Label_36 = uilabel(app.TAB_Filter);
            app.Label_36.Text = '滤波器阶数';
            app.EditField = uieditfield(app.TAB_Filter, 'numeric');
            app.Tab_2 = uitab(app.TABG_Audio);
            app.Tab_2.Title = '调制解调';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Work7

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end